classdef HSVrecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ThresholdHuePanel            matlab.ui.container.Panel
        LoadimageButton              matlab.ui.control.Button
        ThresholdSaturationPanel     matlab.ui.container.Panel
        ThresholdValuePanel          matlab.ui.container.Panel
        SearchcolorsButton           matlab.ui.control.Button
        RedLow                       matlab.ui.control.EditField
        RedHigh                      matlab.ui.control.EditField
        GreenLow                     matlab.ui.control.EditField
        GreenHigh                    matlab.ui.control.EditField
        BlueLow                      matlab.ui.control.EditField
        BlueHigh                     matlab.ui.control.EditField
        SearchblobsButton            matlab.ui.control.Button
        SmallestAcceptableAreaEditFieldLabel  matlab.ui.control.Label
        SmallestAcceptableAreaEditField  matlab.ui.control.EditField
        StructuringElementsizeEditFieldLabel  matlab.ui.control.Label
        StructuringElementsizeEditField  matlab.ui.control.EditField
        BlobparametersTextAreaLabel  matlab.ui.control.Label
        BlobparametersTextArea       matlab.ui.control.TextArea
        HSVrecognitionLabel          matlab.ui.control.Label
        BacktomainwindowButton       matlab.ui.control.Button
        UsermanualButton             matlab.ui.control.Button
        AxesRedHist                  matlab.ui.control.UIAxes
        AxesGreenHist                matlab.ui.control.UIAxes
        AxesOrgImg                   matlab.ui.control.UIAxes
        AxesResultImg                matlab.ui.control.UIAxes
        AxesBlueHist                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        axh;    % for the axes
        lh;     % for the line
        axs;    % for the axes
        ls;     % for the line
        axv;    % for the axes
        lv;     % for the line % Description
        MAINwindow;
        USERmanual;
    end
    
    methods (Access = public)
        
        function [hueThresholdLow,hueThresholdHigh]=lMovingh(app, sourceh)
            sourceh.Position(1,1) = round(sourceh.Position(1,1),3);
            sourceh.Position(2,1) = round(sourceh.Position(2,1),3);
            % Add any other action here triggered by the movement of the sliders
            hueThresholdLow = sourceh.Position(1,1);
            hueThresholdHigh = sourceh.Position(2,1);
            app.RedLow.Value=string(hueThresholdLow);
            app.RedHigh.Value=string(hueThresholdHigh);
        end
        function [saturationThresholdLow,saturationThresholdHigh]=lMovings(app, sources)
            % Always keep the position rounded to 1 decimal place
            sources.Position(1,1) = round(sources.Position(1,1),3);
            sources.Position(2,1) = round(sources.Position(2,1),3);
            % Add any other action here triggered by the movement of the sliders
            saturationThresholdLow = sources.Position(1,1);
            saturationThresholdHigh = sources.Position(2,1);
            app.GreenLow.Value=string(saturationThresholdLow);
            app.GreenHigh.Value=string(saturationThresholdHigh);
        end
        function [valueThresholdLow,valueThresholdHigh]=lMovingv(app, sourcev)
            % Always keep the position rounded to 1 decimal place
            sourcev.Position(1,1) = round(sourcev.Position(1,1),3);
            sourcev.Position(2,1) = round(sourcev.Position(2,1),3);
            % Add any other action here triggered by the movement of the sliders
            valueThresholdLow = sourcev.Position(1,1);
            valueThresholdHigh = sourcev.Position(2,1);
            app.BlueLow.Value=string(valueThresholdLow);
            app.BlueHigh.Value=string(valueThresholdHigh);
        end
        function [blobMeasurementsR,meanRGB, areas, numberOfBlobs] = MeasureBlobs(~,maskImage, redBand, greenBand, blueBand)
            try
                [labeledImage, numberOfBlobs] = bwlabel(maskImage, 8);     % Label each blob so we can make measurements of it
                if numberOfBlobs == 0
                    % Didn't detect any yellow blobs in this image.
                    meanRGB = [0 0 0];
                    areas = 0;
                    blobMeasurementsR=0;
                    return;
                end
                % Get all the blob properties.  Can only pass in originalImage in version R2008a and later.
                blobMeasurementsR = regionprops(labeledImage, redBand, 'area', 'MeanIntensity','BoundingBox');
                blobMeasurementsG = regionprops(labeledImage, greenBand, 'area', 'MeanIntensity','BoundingBox');
                blobMeasurementsB = regionprops(labeledImage, blueBand, 'area', 'MeanIntensity','BoundingBox');
                
                meanRGB = zeros(numberOfBlobs, 3);  % One row for each blob.  One column for each color.
                meanRGB(:,1) = [blobMeasurementsR.MeanIntensity]';
                meanRGB(:,2) = [blobMeasurementsG.MeanIntensity]';
                meanRGB(:,3) = [blobMeasurementsB.MeanIntensity]';
                
                % If redBand etc. are double, the intensities will be in the range of 0-1.
                % Multiply by 255 to get them back into the uint8 range of 0-255.
                
                % Now assign the areas.
                areas = zeros(numberOfBlobs, 3);  % One row for each blob.  One column for each color.
                areas(:,1) = [blobMeasurementsR.Area]';
                areas(:,2) = [blobMeasurementsG.Area]';
                areas(:,3) = [blobMeasurementsB.Area]';
                
                
            catch ME
                callStackString = GetCallStack(ME);
                errorMessage = sprintf('Error in program %s.\nTraceback (most recent at top):\n%s\nError Message:\n%s', ...
                    mfilename, callStackString, ME.message);
                errordlg(errorMessage);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear global;
            app.UIFigure.Position = [100 120 1719 830];
            global smallestAcceptableArea;
            global structuringElement;
            structuringElement = strel('disk', 4);
            smallestAcceptableArea=100;
            %%%%%%%%%REEEEEEEEEED%%%%%%%%%%%%%%%%
            
            % Create the 1D axes
            app.axh = axes( ...
                app.ThresholdHuePanel,'Color','none','YColor','none','XLim',[0,1],'YTick',[], ...
                'XTick',[0,0.2,0.4,0.6,0.8,1],'TickDir','both','TickLength',[0.02,0.03],'XMinorTick', ...
                'on','Units','pixels','Position',[8,25,255,0] ...
                );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.axh);
            app.axh.Toolbar.Visible = 'off';
            % Add the line ROI
            app.lh = images.roi.Line(app.axh,'Position',[0.000,0;0.300,0]);
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            clear('app.lr.Position(1,1)');
            clear('app.lr.Position(2,1)');
            addlistener(app.lh,'MovingROI',@(varargin)lMovingh(app,app.lh));
            
            %%%%%%%%%GREEEEEEEEEEN%%%%%%%%%%%%%%%%
            
            % Create the 1D axes
            app.axs = axes( ...
                app.ThresholdSaturationPanel,'Color','none','YColor','none','XLim',[0,1],'YTick',[], ...
                'XTick',[0,0.2,0.4,0.6,0.8,1],'TickDir','both','TickLength',[0.02,0.03],'XMinorTick', ...
                'on','Units','pixels','Position',[8,25,255,0] ...
                );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.axs);
            app.axs.Toolbar.Visible = 'off';
            % Add the line ROI
            app.ls = images.roi.Line(app.axs,'Position',[0.000,0;0.300,0]);
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.ls,'MovingROI',@(varargin)lMovings(app,app.ls));
            
            %%%%%%%%%BLUEEEEEEEEEE%%%%%%%%%%%%%%%%
            
            % Create the 1D axes
            app.axv = axes( ...
                app.ThresholdValuePanel,'Color','none','YColor','none','XLim',[0,1],'YTick',[], ...
                'XTick',[0,0.2,0.4,0.6,0.8,1],'TickDir','both','TickLength',[0.02,0.03],'XMinorTick', ...
                'on','Units','pixels','Position',[8,25,255,0] ...
                );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.axv);
            app.axv.Toolbar.Visible = 'off';
            % Add the line ROI
            app.lv = images.roi.Line(app.axv,'Position',[0.000,0;0.300,0]);
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.lv,'MovingROI',@(varargin)lMovingv(app,app.lv));
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            global hImage;
            global sImage;
            global vImage;
            
            global rgbImage;
            
            
            [imgName, folder] = uigetfile('*.*', 'Specify an image file');
            [rgbImage, storedColorMap] = imread(imgName);
            
            imshow(rgbImage,'Parent',app.AxesOrgImg);
            
            % Convert RGB image to HSV
            hsvImage = rgb2hsv(rgbImage);
            % Extract out the H, S, and V images individually
            hImage = hsvImage(:,:,1);
            sImage = hsvImage(:,:,2);
            vImage = hsvImage(:,:,3);
            
            [hueCounts, hueBinValues] = imhist(hImage);
            
            plot(app.AxesRedHist,hueBinValues, hueCounts, 'r', 'LineWidth', 2);
            
            [saturationCounts, saturationBinValues] = imhist(sImage);
            
            plot(app.AxesGreenHist,saturationBinValues, saturationCounts,'g', 'LineWidth', 2);
            
            [valueCounts, valueBinValues] = imhist(vImage);
            
            plot(app.AxesBlueHist,valueBinValues, valueCounts,'b', 'LineWidth', 2);
        end

        % Button pushed function: SearchcolorsButton
        function SearchcolorsButtonPushed(app, event)
            global hImage;
            global sImage;
            global vImage;
            
            global hueThresholdLow;
            global hueThresholdHigh;
            global saturationThresholdLow;
            global saturationThresholdHigh;
            global valueThresholdLow;
            global valueThresholdHigh;
            
            global smallestAcceptableArea;
            global structuringElement;
            
            global coloredObjectsMask;
            
            global rgbImage;
            
            global maskedRGBImage;
            
            imshow(rgbImage,'Parent',app.AxesOrgImg);
            
            [hueThresholdLow,hueThresholdHigh]=lMovingh(app, app.lh);
            [saturationThresholdLow,saturationThresholdHigh]=lMovings(app, app.ls);
            [valueThresholdLow,valueThresholdHigh]=lMovingv(app, app.lv);
            
            hueMask = (hImage >= hueThresholdLow) & (hImage <= hueThresholdHigh);
            saturationMask = (sImage >= saturationThresholdLow) & (sImage <= saturationThresholdHigh);
            valueMask = (vImage >= valueThresholdLow) & (vImage <= valueThresholdHigh);
            
            coloredObjectsMask = uint8(hueMask & saturationMask & valueMask);
            
            coloredObjectsMask = uint8(bwareaopen(coloredObjectsMask, smallestAcceptableArea));
            
            coloredObjectsMask = imclose(coloredObjectsMask, structuringElement);
            
            coloredObjectsMask = imfill(logical(coloredObjectsMask), 'holes');
            
            coloredObjectsMask = cast(coloredObjectsMask, 'like', rgbImage);
            
            maskedImageR = coloredObjectsMask .* rgbImage(:,:,1);
            maskedImageG = coloredObjectsMask .* rgbImage(:,:,2);
            maskedImageB = coloredObjectsMask .* rgbImage(:,:,3);
            
            maskedRGBImage = cat(3, maskedImageR, maskedImageG, maskedImageB);
            
            imshow(maskedRGBImage,'Parent',app.AxesResultImg);
            
            
            app.BlobparametersTextArea.Value=('');
        end

        % Button pushed function: SearchblobsButton
        function SearchblobsButtonPushed(app, event)
            global maskedRGBImage;
            global coloredObjectsMask;
            global hImage;
            global sImage;
            global vImage;
            
            
            [blobMeasurementsR,meanRGB, areas, numberOfBlobs] = MeasureBlobs(app,coloredObjectsMask, hImage, sImage, vImage);
            
            t=struct2table(blobMeasurementsR);
            t1=t(:,2);
            t1=table2array(t1);
            imshow(maskedRGBImage,'Parent',app.AxesResultImg);
            
            for i=1:height(t1)
                rectangle(app.AxesResultImg, 'Position', t1(i,:),'EdgeColor','b', 'LineWidth', 0.7);
                rectangle(app.AxesOrgImg, 'Position', t1(i,:),'EdgeColor','black', 'LineWidth', 0.7);
            end
            
            if numberOfBlobs > 0
                b={};
                
                for blobNumber = 1 : numberOfBlobs
                    b{blobNumber,1}=char(sprintf('#  %5d,%14d,       %6.2f,       %6.2f,        %6.2f\n', blobNumber, areas(blobNumber), ...
                        meanRGB(blobNumber, 1), meanRGB(blobNumber, 2), meanRGB(blobNumber, 3)));
                    app.BlobparametersTextArea.Value=b;
                end
                b{(numberOfBlobs+1),1}=sprintf(['\n-----------------------------------------------------------------------\n' ...
                    'Blob #,  Area in Pixels,   Mean H,   Mean S,   Mean V\n' ...
                    '-----------------------------------------------------------------------\n']);
                app.BlobparametersTextArea.Value=b;
            else
                % Alert user that no red blobs were found.
                message = sprintf('No red blobs were found in the image:\n%s', fullImageFileName);
                fprintf(1, '\n%s\n', message);
                uiwait(msgbox(message));
            end
            
        end

        % Value changed function: RedLow
        function RedLowValueChanged(app, event)
            global hueThresholdLow;
            value = app.RedLow.Value;
            app.lh.Position(1,1)=str2double(value);
            [hueThresholdLow,~]=lMovingh(app, app.lh);
        end

        % Value changed function: RedHigh
        function RedHighValueChanged(app, event)
            global hueThresholdHigh;
            value = app.RedHigh.Value;
            app.lh.Position(2,1)=str2double(value);
            [~,hueThresholdHigh]=lMovingh(app, app.lh);
        end

        % Value changed function: GreenLow
        function GreenLowValueChanged(app, event)
            global saturationThresholdLow;
            value = app.GreenLow.Value;
            app.ls.Position(1,1)=str2double(value);
            [saturationThresholdLow,~]=lMovings(app, app.ls);
        end

        % Value changed function: GreenHigh
        function GreenHighValueChanged(app, event)
            global saturationThresholdHigh;
            value = app.GreenHigh.Value;
            app.ls.Position(2,1)=str2double(value);
            [~,saturationThresholdHigh]=lMovings(app, app.ls);
        end

        % Value changed function: BlueLow
        function BlueLowValueChanged(app, event)
            global valueThresholdLow;
            value = app.BlueLow.Value;
            app.lv.Position(1,1)=str2double(value);
            [valueThresholdLow,~]=lMovingv(app, app.lv);
        end

        % Value changed function: BlueHigh
        function BlueHighValueChanged(app, event)
            global valueThresholdHigh;
            value = app.BlueHigh.Value;
            app.lv.Position(2,1)=str2double(value);
            [~,valueThresholdHigh]=lMovingv(app, app.lv);
        end

        % Value changed function: SmallestAcceptableAreaEditField
        function SmallestAcceptableAreaEditFieldValueChanged(app, event)
            global smallestAcceptableArea;
            value = app.SmallestAcceptableAreaEditField.Value;
            smallestAcceptableArea=str2double(value);
        end

        % Value changed function: StructuringElementsizeEditField
        function StructuringElementsizeEditFieldValueChanged(app, event)
            global structuringElement;
            value = app.StructuringElementsizeEditField.Value;
            structuringElement = strel('square', str2double(value));
        end

        % Button pushed function: BacktomainwindowButton
        function BacktomainwindowButtonPushed(app, event)
            app.MAINwindow=MAINwindow;
            delete(app);
        end

        % Button pushed function: UsermanualButton
        function UsermanualButtonPushed(app, event)
            app.USERmanual=USERmanual;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ThresholdHuePanel = uipanel(app.UIFigure);
            app.ThresholdHuePanel.Title = 'Threshold Hue';
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.ThresholdSaturationPanel = uipanel(app.UIFigure);
            app.ThresholdSaturationPanel.Title = 'Threshold Saturation';
            app.ThresholdValuePanel = uipanel(app.UIFigure);
            app.ThresholdValuePanel.Title = 'Threshold Value';
            app.SearchcolorsButton = uibutton(app.UIFigure, 'push');
            app.SearchcolorsButton.ButtonPushedFcn = createCallbackFcn(app, @SearchcolorsButtonPushed, true);
            app.SearchcolorsButton.Text = 'Search colors';
            app.RedLow = uieditfield(app.UIFigure, 'text');
            app.RedLow.ValueChangedFcn = createCallbackFcn(app, @RedLowValueChanged, true);
            app.RedLow.Value = '0';
            app.RedHigh = uieditfield(app.UIFigure, 'text');
            app.RedHigh.ValueChangedFcn = createCallbackFcn(app, @RedHighValueChanged, true);
            app.RedHigh.Value = '0.300';
            app.GreenLow = uieditfield(app.UIFigure, 'text');
            app.GreenLow.ValueChangedFcn = createCallbackFcn(app, @GreenLowValueChanged, true);
            app.GreenLow.Value = '0';
            app.GreenHigh = uieditfield(app.UIFigure, 'text');
            app.GreenHigh.ValueChangedFcn = createCallbackFcn(app, @GreenHighValueChanged, true);
            app.GreenHigh.Value = '0.300';
            app.BlueLow = uieditfield(app.UIFigure, 'text');
            app.BlueLow.ValueChangedFcn = createCallbackFcn(app, @BlueLowValueChanged, true);
            app.BlueLow.Value = '0';
            app.BlueHigh = uieditfield(app.UIFigure, 'text');
            app.BlueHigh.ValueChangedFcn = createCallbackFcn(app, @BlueHighValueChanged, true);
            app.BlueHigh.Value = '0.300';
            app.SearchblobsButton = uibutton(app.UIFigure, 'push');
            app.SearchblobsButton.ButtonPushedFcn = createCallbackFcn(app, @SearchblobsButtonPushed, true);
            app.SearchblobsButton.Text = 'Search blobs';
            app.SmallestAcceptableAreaEditFieldLabel = uilabel(app.UIFigure);
            app.SmallestAcceptableAreaEditFieldLabel.Text = 'Smallest Acceptable Area: ';
            app.SmallestAcceptableAreaEditField = uieditfield(app.UIFigure, 'text');
            app.SmallestAcceptableAreaEditField.ValueChangedFcn = createCallbackFcn(app, @SmallestAcceptableAreaEditFieldValueChanged, true);
            app.SmallestAcceptableAreaEditField.Value = '100';
            app.StructuringElementsizeEditFieldLabel = uilabel(app.UIFigure);
            app.StructuringElementsizeEditFieldLabel.Text = 'Structuring Element (size) :';
            app.StructuringElementsizeEditField = uieditfield(app.UIFigure, 'text');
            app.StructuringElementsizeEditField.ValueChangedFcn = createCallbackFcn(app, @StructuringElementsizeEditFieldValueChanged, true);
            app.StructuringElementsizeEditField.Value = '4';
            app.BlobparametersTextAreaLabel = uilabel(app.UIFigure);
            app.BlobparametersTextAreaLabel.Text = {'Blob '; 'parameters'};
            app.BlobparametersTextArea = uitextarea(app.UIFigure);
            app.HSVrecognitionLabel = uilabel(app.UIFigure);
            app.HSVrecognitionLabel.Text = 'HSV recognition';
            app.BacktomainwindowButton = uibutton(app.UIFigure, 'push');
            app.BacktomainwindowButton.ButtonPushedFcn = createCallbackFcn(app, @BacktomainwindowButtonPushed, true);
            app.BacktomainwindowButton.Text = 'Back to main window';
            app.UsermanualButton = uibutton(app.UIFigure, 'push');
            app.UsermanualButton.ButtonPushedFcn = createCallbackFcn(app, @UsermanualButtonPushed, true);
            app.UsermanualButton.Text = 'User manual';
            app.AxesRedHist = uiaxes(app.UIFigure);
            title(app.AxesRedHist, 'Hue histogram')
            xlabel(app.AxesRedHist, 'Hue value')
            ylabel(app.AxesRedHist, 'Pixel Count')
            zlabel(app.AxesRedHist, 'Z')
            app.AxesGreenHist = uiaxes(app.UIFigure);
            title(app.AxesGreenHist, 'Saturation histogram')
            xlabel(app.AxesGreenHist, 'Saturation value')
            ylabel(app.AxesGreenHist, 'Pixel Count')
            zlabel(app.AxesGreenHist, 'Z')
            app.AxesOrgImg = uiaxes(app.UIFigure);
            title(app.AxesOrgImg, 'Orginal image')
            app.AxesOrgImg.XTick = [];
            app.AxesOrgImg.YTick = [];
            app.AxesOrgImg.ZTick = [];
            app.AxesResultImg = uiaxes(app.UIFigure);
            title(app.AxesResultImg, 'Result')
            app.AxesResultImg.XTick = [];
            app.AxesResultImg.YTick = [];
            app.AxesResultImg.ZTick = [];
            app.AxesBlueHist = uiaxes(app.UIFigure);
            title(app.AxesBlueHist, 'Value histogram')
            xlabel(app.AxesBlueHist, 'Value value')
            ylabel(app.AxesBlueHist, 'Pixel Count')
            zlabel(app.AxesBlueHist, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HSVrecognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end