classdef MAINwindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image                 matlab.ui.control.Image
        RGBrecognitionButton  matlab.ui.control.Button
        HSVrecognitionButton  matlab.ui.control.Button
        UsermanualButton      matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
    end

    
    properties (Access = private)
        RGBrecognition
        HSVrecognition % Description
        USERmanual;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [709 451 502 178];
        end

        % Button pushed function: UsermanualButton
        function UsermanualButtonPushed(app, event)
            app.USERmanual=USERmanual;
        end

        % Button pushed function: RGBrecognitionButton
        function RGBrecognitionButtonPushed(app, event)
            app.RGBrecognition=RGBrecognition;
            delete(app);
        end

        % Button pushed function: HSVrecognitionButton
        function HSVrecognitionButtonPushed(app, event)
            app.HSVrecognition=HSVrecognition;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'colorful-watercolor-rainbow-background_125540-151.jpg';
            app.RGBrecognitionButton = uibutton(app.UIFigure, 'push');
            app.RGBrecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @RGBrecognitionButtonPushed, true);
            app.RGBrecognitionButton.Text = {'RGB '; 'recognition'};
            app.HSVrecognitionButton = uibutton(app.UIFigure, 'push');
            app.HSVrecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @HSVrecognitionButtonPushed, true);
            app.HSVrecognitionButton.Text = {'HSV '; 'recognition'};
            app.UsermanualButton = uibutton(app.UIFigure, 'push');
            app.UsermanualButton.ButtonPushedFcn = createCallbackFcn(app, @UsermanualButtonPushed, true);
            app.UsermanualButton.Text = {'User'; 'manual'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Select the color model in which you want to search for objects in the image.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'COLOR DETECTOR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAINwindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end