classdef RGBrecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ThresholdRedPanel            matlab.ui.container.Panel
        LoadimageButton              matlab.ui.control.Button
        ThresholdGreenPanel          matlab.ui.container.Panel
        ThresholdBluePanel           matlab.ui.container.Panel
        SearchcolorsButton           matlab.ui.control.Button
        RedLow                       matlab.ui.control.EditField
        RedHigh                      matlab.ui.control.EditField
        GreenLow                     matlab.ui.control.EditField
        GreenHigh                    matlab.ui.control.EditField
        BlueLow                      matlab.ui.control.EditField
        BlueHigh                     matlab.ui.control.EditField
        SmallestAcceptableAreaLabel  matlab.ui.control.Label
        SmallestAcceptableAreaEditField  matlab.ui.control.EditField
        StructuringElementsizeEditFieldLabel  matlab.ui.control.Label
        StructuringElementsizeEditField  matlab.ui.control.EditField
        SearchblobsButton            matlab.ui.control.Button
        BlobparametersTextAreaLabel  matlab.ui.control.Label
        BlobparametersTextArea       matlab.ui.control.TextArea
        RGBrecognitionLabel          matlab.ui.control.Label
        BacktomainwindowButton       matlab.ui.control.Button
        UsermanualButton             matlab.ui.control.Button
        AxesRedHist                  matlab.ui.control.UIAxes
        AxesGreenHist                matlab.ui.control.UIAxes
        AxesBlueHist                 matlab.ui.control.UIAxes
        AxesResultImg                matlab.ui.control.UIAxes
        AxesOrgImg                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        axr;    % for the axes
        lr;     % for the line
        axg;    % for the axes
        lg;     % for the line
        axb;    % for the axes
        lb;     % for the line
        MAINwindow;
        USERmanual;
    end
    
    
    methods (Access = public)
        function [redThresholdLow,redThresholdHigh]=lMovingr(app, sourcer)
            sourcer.Position(1,1) = round(sourcer.Position(1,1),0);
            sourcer.Position(2,1) = round(sourcer.Position(2,1),0);
            % Add any other action here triggered by the movement of the sliders
            redThresholdLow = sourcer.Position(1,1);
            redThresholdHigh = sourcer.Position(2,1);
            app.RedLow.Value=string(redThresholdLow);
            app.RedHigh.Value=string(redThresholdHigh);
        end
        function [greenThresholdLow,greenThresholdHigh]=lMovingg(app, sourceg)
            % Always keep the position rounded to 1 decimal place
            sourceg.Position(1,1) = round(sourceg.Position(1,1),0);
            sourceg.Position(2,1) = round(sourceg.Position(2,1),0);
            % Add any other action here triggered by the movement of the sliders
            greenThresholdLow = sourceg.Position(1,1);
            greenThresholdHigh = sourceg.Position(2,1);
            app.GreenLow.Value=string(greenThresholdLow);
            app.GreenHigh.Value=string(greenThresholdHigh);
        end
        function [blueThresholdLow,blueThresholdHigh]=lMovingb(app, sourceb)
            % Always keep the position rounded to 1 decimal place
            sourceb.Position(1,1) = round(sourceb.Position(1,1),0);
            sourceb.Position(2,1) = round(sourceb.Position(2,1),0);
            % Add any other action here triggered by the movement of the sliders
            blueThresholdLow = sourceb.Position(1,1);
            blueThresholdHigh = sourceb.Position(2,1);
            app.BlueLow.Value=string(blueThresholdLow);
            app.BlueHigh.Value=string(blueThresholdHigh);
        end
        function [blobMeasurementsR,meanRGB, areas, numberOfBlobs] = MeasureBlobs(~,maskImage, redBand, greenBand, blueBand)
            try
                [labeledImage, numberOfBlobs] = bwlabel(maskImage, 8);     % Label each blob so we can make measurements of it
                if numberOfBlobs == 0
                    % Didn't detect any yellow blobs in this image.
                    meanRGB = [0 0 0];
                    areas = 0;
                    blobMeasurementsR=0;
                    return;
                end
                % Get all the blob properties.  Can only pass in originalImage in version R2008a and later.
                blobMeasurementsR = regionprops(labeledImage, redBand, 'area', 'MeanIntensity','BoundingBox');
                blobMeasurementsG = regionprops(labeledImage, greenBand, 'area', 'MeanIntensity','BoundingBox');
                blobMeasurementsB = regionprops(labeledImage, blueBand, 'area', 'MeanIntensity','BoundingBox');
                
                meanRGB = zeros(numberOfBlobs, 3);  % One row for each blob.  One column for each color.
                meanRGB(:,1) = [blobMeasurementsR.MeanIntensity]';
                meanRGB(:,2) = [blobMeasurementsG.MeanIntensity]';
                meanRGB(:,3) = [blobMeasurementsB.MeanIntensity]';
                
                % If redBand etc. are double, the intensities will be in the range of 0-1.
                % Multiply by 255 to get them back into the uint8 range of 0-255.
                if ~strcmpi(class(redBand), 'uint8')
                    meanRGB = meanRGB * 255.0;
                end
                
                % Now assign the areas.
                areas = zeros(numberOfBlobs, 3);  % One row for each blob.  One column for each color.
                areas(:,1) = [blobMeasurementsR.Area]';
                areas(:,2) = [blobMeasurementsG.Area]';
                areas(:,3) = [blobMeasurementsB.Area]';
                
                
            catch ME
                callStackString = GetCallStack(ME);
                errorMessage = sprintf('Error in program %s.\nTraceback (most recent at top):\n%s\nError Message:\n%s', ...
                    mfilename, callStackString, ME.message);
                errordlg(errorMessage);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear global;
            app.UIFigure.Position = [100 120 1719 830];
            global smallestAcceptableArea;
            global structuringElement;
            structuringElement = strel('square', 2);
            smallestAcceptableArea=100;
            %%%%%%%%%REEEEEEEEEED%%%%%%%%%%%%%%%%
            
            % Create the 1D axes
            app.axr = axes( ...
                app.ThresholdRedPanel,'Color','none','YColor','none','XLim',[0,255],'YTick',[], ...
                'XTick',[0,50,100,150,200,250],'TickDir','both','TickLength',[0.02,0.030],'XMinorTick', ...
                'on','Units','pixels','Position',[8,25,255,0] ...
                );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.axr);
            app.axr.Toolbar.Visible = 'off';
            % Add the line ROI
            app.lr = images.roi.Line(app.axr,'Position',[85,0;255,0]);
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            clear('app.lr.Position(1,1)');
            clear('app.lr.Position(2,1)');
            addlistener(app.lr,'MovingROI',@(varargin)lMovingr(app,app.lr));
            
            %%%%%%%%%GREEEEEEEEEEN%%%%%%%%%%%%%%%%
            
            % Create the 1D axes
            app.axg = axes( ...
                app.ThresholdGreenPanel,'Color','none','YColor','none','XLim',[0,255],'YTick',[], ...
                'XTick',[0,50,100,150,200,250],'TickDir','both','TickLength',[0.02,0.030],'XMinorTick', ...
                'on','Units','pixels','Position',[8,25,255,0] ...
                );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.axg);
            app.axg.Toolbar.Visible = 'off';
            % Add the line ROI
            app.lg = images.roi.Line(app.axg,'Position',[0,0;60,0]);
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.lg,'MovingROI',@(varargin)lMovingg(app,app.lg));
            
            %%%%%%%%%BLUEEEEEEEEEE%%%%%%%%%%%%%%%%
            
            % Create the 1D axes
            app.axb = axes( ...
                app.ThresholdBluePanel,'Color','none','YColor','none','XLim',[0,255],'YTick',[], ...
                'XTick',[0,50,100,150,200,250],'TickDir','both','TickLength',[0.02,0.030],'XMinorTick', ...
                'on','Units','pixels','Position',[8,25,255,0] ...
                );
            % Disable the interactivity & toolbar visibility
            disableDefaultInteractivity(app.axb);
            app.axb.Toolbar.Visible = 'off';
            % Add the line ROI
            app.lb = images.roi.Line(app.axb,'Position',[0,0;90,0]);
            % Add a listener that will trigger a callback function titled "lMoving" when user
            % moves the ROI endpoints or the line ROI as a whole
            addlistener(app.lb,'MovingROI',@(varargin)lMovingb(app,app.lb));
            
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            global redBand;
            global greenBand;
            global blueBand;
            
            global rgbImage;
            
            [imgName, folder] = uigetfile('*.*', 'Specify an image file');
            [rgbImage, storedColorMap] = imread(imgName);
            [rows, columns, numberOfColorBands] = size(rgbImage);
            
            imshow(rgbImage,'Parent',app.AxesOrgImg);
            
            redBand = rgbImage(:, :, 1);
            greenBand = rgbImage(:, :, 2);
            blueBand = rgbImage(:, :, 3);
            
            [countsR, grayLevelsR] = imhist(redBand);
            
            plot(app.AxesRedHist,grayLevelsR, countsR, 'r', 'LineWidth', 2);
            
            [countsG, grayLevelsG] = imhist(greenBand);
            
            plot(app.AxesGreenHist,grayLevelsG, countsG, 'g', 'LineWidth', 2);
            
            [countsB, grayLevelsB] = imhist(blueBand);
            
            plot(app.AxesBlueHist,grayLevelsB, countsB, 'b', 'LineWidth', 2);
        end

        % Button pushed function: SearchcolorsButton
        function SearchcolorsButtonPushed(app, event)
            global redBand;
            global greenBand;
            global blueBand;
            
            global redThresholdLow;
            global redThresholdHigh
            global greenThresholdLow
            global greenThresholdHigh
            global blueThresholdLow
            global blueThresholdHigh
            
            global smallestAcceptableArea;
            global structuringElement;
            
            global redObjectsMask;
            
            global maskedRGBImage;
            
            global rgbImage;
            
            imshow(rgbImage,'Parent',app.AxesOrgImg);
            
            [redThresholdLow,redThresholdHigh]=lMovingr(app, app.lr);
            [greenThresholdLow,greenThresholdHigh]=lMovingg(app, app.lg);
            [blueThresholdLow,blueThresholdHigh]=lMovingb(app, app.lb);


            redMask = (redBand >= redThresholdLow) & (redBand <= redThresholdHigh);
            greenMask = (greenBand >= greenThresholdLow) & (greenBand <= greenThresholdHigh);
            blueMask = (blueBand >= blueThresholdLow) & (blueBand <= blueThresholdHigh);
            
            redObjectsMask = uint8(redMask & greenMask & blueMask);
            
            
            redObjectsMask = uint8(bwareaopen(redObjectsMask, smallestAcceptableArea));
            
            
            redObjectsMask = imclose(redObjectsMask, structuringElement);
            
            % Fill in any holes in the regions, since they are most likely red also.
            redObjectsMask = uint8(imfill(redObjectsMask, 'holes'));
            
            redObjectsMask = cast(redObjectsMask, class(redBand));
            
            % Use the red object mask to mask out the red-only portions of the rgb image.
            maskedImageR = redObjectsMask .* redBand;
            maskedImageG = redObjectsMask .* greenBand;
            maskedImageB = redObjectsMask .* blueBand;
            
            maskedRGBImage = cat(3, maskedImageR, maskedImageG, maskedImageB);
            
            imshow(maskedRGBImage,'Parent',app.AxesResultImg);
            
            app.BlobparametersTextArea.Value=('');
        end

        % Value changed function: RedLow
        function RedLowValueChanged(app, event)
            global redThresholdLow;
            value = app.RedLow.Value;
            app.lr.Position(1,1)=str2double(value);
            [redThresholdLow,~]=lMovingr(app, app.lr);
            
        end

        % Value changed function: RedHigh
        function RedHighValueChanged(app, event)
            global redThresholdHigh;
            value = app.RedHigh.Value;
            app.lr.Position(2,1)=str2double(value);
            [~,redThresholdHigh]=lMovingr(app, app.lr);
        end

        % Value changed function: GreenLow
        function GreenLowValueChanged(app, event)
            global greenThresholdLow;
            
            value = app.GreenLow.Value;
            app.lg.Position(1,1)=str2double(value);
            [greenThresholdLow,~]=lMovingg(app, app.lg);
            
        end

        % Value changed function: GreenHigh
        function GreenHighValueChanged(app, event)
            global greenThresholdHigh;
            
            value = app.GreenHigh.Value;
            app.lg.Position(2,1)=str2double(value);
            [~,greenThresholdHigh]=lMovingg(app, app.lg);
        end

        % Value changed function: BlueLow
        function BlueLowValueChanged(app, event)
            global blueThresholdLow;
            
            value = app.BlueLow.Value;
            app.lb.Position(1,1)=str2double(value);
            [blueThresholdLow,~]=lMovingb(app, app.lb);
        end

        % Value changed function: BlueHigh
        function BlueHighValueChanged(app, event)
            global blueThresholdHigh;
            
            value = app.BlueHigh.Value;
            app.lb.Position(2,1)=str2double(value);
            [~,blueThresholdHigh]=lMovingb(app, app.lb);
        end

        % Value changed function: SmallestAcceptableAreaEditField
        function SmallestAcceptableAreaEditFieldValueChanged(app, event)
            global smallestAcceptableArea;
            value = app.SmallestAcceptableAreaEditField.Value;
            smallestAcceptableArea=str2double(value);
        end

        % Value changed function: StructuringElementsizeEditField
        function StructuringElementsizeEditFieldValueChanged(app, event)
            global structuringElement;
            value = app.StructuringElementsizeEditField.Value;
            structuringElement = strel('square', str2double(value));
        end

        % Button pushed function: SearchblobsButton
        function SearchblobsButtonPushed(app, event)
            global redObjectsMask;
            global redBand;
            global greenBand;
            global blueBand;
            global maskedRGBImage;
            
            
            [blobMeasurementsR,meanRGB, areas, numberOfBlobs] = MeasureBlobs(app,redObjectsMask, redBand, greenBand, blueBand);
            t=struct2table(blobMeasurementsR);
            t1=t(:,2);
            t1=table2array(t1);
            
            imshow(maskedRGBImage,'Parent',app.AxesResultImg);

            for i=1:height(t1)
                rectangle(app.AxesResultImg, 'Position', t1(i,:),'EdgeColor','b', 'LineWidth', 0.7);
                rectangle(app.AxesOrgImg, 'Position', t1(i,:),'EdgeColor','black', 'LineWidth', 0.7);
            end
            
            if numberOfBlobs > 0
                b={};
                
                for blobNumber = 1 : numberOfBlobs
                    b{blobNumber,1}=char(sprintf('#  %5d,%14d,          %6.2f,      %6.2f,       %6.2f\n', blobNumber, areas(blobNumber), ...
                        meanRGB(blobNumber, 1), meanRGB(blobNumber, 2), meanRGB(blobNumber, 3)));
                    app.BlobparametersTextArea.Value=b;
                end
                b{(numberOfBlobs+1),1}=sprintf(['\n----------------------------------------------------------------------------\n' ...
                    'Blob #,  Area in Pixels,   Mean R,     Mean G,     Mean B\n' ...
                    '----------------------------------------------------------------------------\n']);
                app.BlobparametersTextArea.Value=b;
            else
                % Alert user that no red blobs were found.
                message = sprintf('No red blobs were found in the image:\n%s', fullImageFileName);
                fprintf(1, '\n%s\n', message);
                uiwait(msgbox(message));
            end
            
        end

        % Button pushed function: BacktomainwindowButton
        function BacktomainwindowButtonPushed(app, event)
            app.MAINwindow=MAINwindow;
            delete(app);
        end

        % Button pushed function: UsermanualButton
        function UsermanualButtonPushed(app, event)
            app.USERmanual=USERmanual;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ThresholdRedPanel = uipanel(app.UIFigure);
            app.ThresholdRedPanel.Title = 'Threshold Red';
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.ThresholdGreenPanel = uipanel(app.UIFigure);
            app.ThresholdGreenPanel.Title = 'Threshold Green';
            app.ThresholdBluePanel = uipanel(app.UIFigure);
            app.ThresholdBluePanel.Title = 'Threshold Blue';
            app.SearchcolorsButton = uibutton(app.UIFigure, 'push');
            app.SearchcolorsButton.ButtonPushedFcn = createCallbackFcn(app, @SearchcolorsButtonPushed, true);
            app.SearchcolorsButton.Text = 'Search colors';
            app.RedLow = uieditfield(app.UIFigure, 'text');
            app.RedLow.ValueChangedFcn = createCallbackFcn(app, @RedLowValueChanged, true);
            app.RedLow.Value = '85';
            app.RedHigh = uieditfield(app.UIFigure, 'text');
            app.RedHigh.ValueChangedFcn = createCallbackFcn(app, @RedHighValueChanged, true);
            app.RedHigh.Value = '255';
            app.GreenLow = uieditfield(app.UIFigure, 'text');
            app.GreenLow.ValueChangedFcn = createCallbackFcn(app, @GreenLowValueChanged, true);
            app.GreenLow.Value = '0';
            app.GreenHigh = uieditfield(app.UIFigure, 'text');
            app.GreenHigh.ValueChangedFcn = createCallbackFcn(app, @GreenHighValueChanged, true);
            app.GreenHigh.Value = '60';
            app.BlueLow = uieditfield(app.UIFigure, 'text');
            app.BlueLow.ValueChangedFcn = createCallbackFcn(app, @BlueLowValueChanged, true);
            app.BlueLow.Value = '0';
            app.BlueHigh = uieditfield(app.UIFigure, 'text');
            app.BlueHigh.ValueChangedFcn = createCallbackFcn(app, @BlueHighValueChanged, true);
            app.BlueHigh.Value = '90';
            app.SmallestAcceptableAreaLabel = uilabel(app.UIFigure);
            app.SmallestAcceptableAreaLabel.Text = 'Smallest Acceptable Area: ';
            app.SmallestAcceptableAreaEditField = uieditfield(app.UIFigure, 'text');
            app.SmallestAcceptableAreaEditField.ValueChangedFcn = createCallbackFcn(app, @SmallestAcceptableAreaEditFieldValueChanged, true);
            app.SmallestAcceptableAreaEditField.Value = '100';
            app.StructuringElementsizeEditFieldLabel = uilabel(app.UIFigure);
            app.StructuringElementsizeEditFieldLabel.Text = 'Structuring Element (size) :';
            app.StructuringElementsizeEditField = uieditfield(app.UIFigure, 'text');
            app.StructuringElementsizeEditField.ValueChangedFcn = createCallbackFcn(app, @StructuringElementsizeEditFieldValueChanged, true);
            app.StructuringElementsizeEditField.Value = '2';
            app.SearchblobsButton = uibutton(app.UIFigure, 'push');
            app.SearchblobsButton.ButtonPushedFcn = createCallbackFcn(app, @SearchblobsButtonPushed, true);
            app.SearchblobsButton.Text = 'Search blobs';
            app.BlobparametersTextAreaLabel = uilabel(app.UIFigure);
            app.BlobparametersTextAreaLabel.Text = {'Blob '; 'parameters'};
            app.BlobparametersTextArea = uitextarea(app.UIFigure);
            app.RGBrecognitionLabel = uilabel(app.UIFigure);
            app.RGBrecognitionLabel.Text = 'RGB recognition';
            app.BacktomainwindowButton = uibutton(app.UIFigure, 'push');
            app.BacktomainwindowButton.ButtonPushedFcn = createCallbackFcn(app, @BacktomainwindowButtonPushed, true);
            app.BacktomainwindowButton.Text = 'Back to main window';
            app.UsermanualButton = uibutton(app.UIFigure, 'push');
            app.UsermanualButton.ButtonPushedFcn = createCallbackFcn(app, @UsermanualButtonPushed, true);
            app.UsermanualButton.Text = 'User manual';
            app.AxesRedHist = uiaxes(app.UIFigure);
            title(app.AxesRedHist, 'Red band histogram')
            xlabel(app.AxesRedHist, 'Gray Levels')
            ylabel(app.AxesRedHist, 'Pixel Count')
            zlabel(app.AxesRedHist, 'Z')
            app.AxesRedHist.XLim = [0 255];
            app.AxesGreenHist = uiaxes(app.UIFigure);
            title(app.AxesGreenHist, 'Green band histogram')
            xlabel(app.AxesGreenHist, 'Gray Levels')
            ylabel(app.AxesGreenHist, 'Pixel Count')
            zlabel(app.AxesGreenHist, 'Z')
            app.AxesGreenHist.XLim = [0 255];
            app.AxesBlueHist = uiaxes(app.UIFigure);
            title(app.AxesBlueHist, 'Blue band histogram')
            xlabel(app.AxesBlueHist, 'Gray Levels')
            ylabel(app.AxesBlueHist, 'Pixel Count')
            zlabel(app.AxesBlueHist, 'Z')
            app.AxesBlueHist.XLim = [0 255];
            app.AxesResultImg = uiaxes(app.UIFigure);
            title(app.AxesResultImg, 'Result')
            app.AxesResultImg.XTick = [];
            app.AxesResultImg.YTick = [];
            app.AxesResultImg.ZTick = [];
            app.AxesOrgImg = uiaxes(app.UIFigure);
            title(app.AxesOrgImg, 'Orginal image')
            app.AxesOrgImg.XTick = [];
            app.AxesOrgImg.YTick = [];
            app.AxesOrgImg.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RGBrecognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end